classdef copertura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IniziaredalnumerodistudentiesuccessivamenteTextArea  matlab.ui.control.TextArea
        FrazioneopzionisultotaleButtonGroup  matlab.ui.container.ButtonGroup
        button1                       matlab.ui.control.RadioButton
        button075                     matlab.ui.control.RadioButton
        button05                      matlab.ui.control.RadioButton
        button025                     matlab.ui.control.RadioButton
        button0                       matlab.ui.control.RadioButton
        NumerostudentimigliaiaSlider  matlab.ui.control.Slider
        NumerostudentimigliaiaSliderLabel  matlab.ui.control.Label
        CoperturatotaleSlider         matlab.ui.control.Slider
        CoperturaLabel                matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CoperturatotaleSlider, CoperturatotaleSlider, 
        % ...and 5 other components
        function CoperturaSliderValueChanged(app, event)
            app.UIAxes.XLim=[1 1.50];
            
            % copertura totale in percentuale
            copertura = app.CoperturatotaleSlider.Value/100; 

            % numero di studenti attesi
            nstudenti = app.NumerostudentimigliaiaSlider.Value*1000; 
                    
            % diversi valori per capire quanto copro in opzioni e 
            % quindi il restante in contratti 
            if app.button0.Value==1
                fraccall=0;
            elseif app.button025.Value==1
                fraccall=0.25; % per esempio copro il 25% della copertura 
                               % totale in opzioni e il 75% in contratti
            elseif app.button05.Value==1
               fraccall=0.5;
            elseif app.button075.Value==1
                fraccall=0.75;
            elseif app.button1.Value==1
                fraccall=1;
            end

            prezzospot = linspace(1.01,1.48,48); % valori possibili del cambio €/$

            % assumiamo di firmare i contratti e le opzioni ipotizzando
            % un valore fisso di possibili 25 mila studenti/clienti
            studenticopertiopzioni = 25000*copertura*fraccall;
            studenticoperticontratti = 25000*copertura*(1-fraccall);

            % prezzo dell'opzione
            prezzoopzione = studenticopertiopzioni*1.22*0.05;

            payoffopzione = max((prezzospot-1.22),0); % payoff opzione

            % studenti coperti con contratti
            contrattipagati = (nstudenti*(1-fraccall)*copertura-studenticoperticontratti)*(1.22-prezzospot);
            
            % variabili ausiliare che permettono di capire se si ufruisce
            % dell'opzione oppure no
            usoopzione = payoffopzione>0;
            noopzione = payoffopzione<=0;

            % profitto nel caso di copertura mista di opzioni e contratti                      
            profitto = nstudenti*copertura*fraccall*noopzione.*(1.22-prezzospot)... %caso no opzione
                + (studenticopertiopzioni-nstudenti*copertura*fraccall)*usoopzione.*payoffopzione... % caso opzione
                - prezzoopzione...
                + contrattipagati...
                + nstudenti*(1-copertura)*(1.22-prezzospot);          

            % profittonew nel caso in cui copro 10 mila studenti (volume
            % minimo atteso) con contratti e il restante (15 mila studenti)
            % con opzioni
            prezzoopzionenew = 15000*1.22*0.05;

            profittonew = (nstudenti-10000)*noopzione.*(1.22-prezzospot)...
                +(15000-(nstudenti-10000))*usoopzione.*payoffopzione...
                -prezzoopzionenew; 

            % plot dinamici
            plot(app.UIAxes2,prezzospot,profittonew, [1.01;1.48],[0;0],':');
            plot(app.UIAxes,prezzospot,profitto,[1.01;1.48],[0;0],':');
        end

        % Callback function
        function NumerostudentimigliaiaSliderValueChanged(app, event)
            nstudenti = app.NumerostudentimigliaiaSlider.Value*1000;
            
        end

        % Callback function
        function NumerostudentimigliaiaSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function ButtonGroupButtonDown(app, event)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Parte opzioni e contratti')
            xlabel(app.UIAxes, 'Cambio €/$')
            ylabel(app.UIAxes, 'Profitto')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [1.01 1.48];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Contratti sul volume minimo, resto opzioni')
            xlabel(app.UIAxes2, 'Cambio €/$')
            ylabel(app.UIAxes2, 'Profitto')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [1.01 1.48];
            app.CoperturaLabel = uilabel(app.UIFigure);
            app.CoperturaLabel.Text = {'Copertura '; '% totale'};
            app.CoperturatotaleSlider = uislider(app.UIFigure);
            app.CoperturatotaleSlider.MajorTicks = [0 25 50 75 100];
            app.CoperturatotaleSlider.ValueChangedFcn = createCallbackFcn(app, @CoperturaSliderValueChanged, true);
            app.CoperturatotaleSlider.ValueChangingFcn = createCallbackFcn(app, @CoperturaSliderValueChanged, true);
            app.CoperturatotaleSlider.Value = 100;
            app.NumerostudentimigliaiaSliderLabel = uilabel(app.UIFigure);
            app.NumerostudentimigliaiaSliderLabel.Text = {'Numero'; 'studenti'; 'migliaia'};
            app.NumerostudentimigliaiaSlider = uislider(app.UIFigure);
            app.NumerostudentimigliaiaSlider.Limits = [10 30];
            app.NumerostudentimigliaiaSlider.MajorTicks = [10 15 20 25 30];
            app.NumerostudentimigliaiaSlider.ValueChangedFcn = createCallbackFcn(app, @CoperturaSliderValueChanged, true);
            app.NumerostudentimigliaiaSlider.ValueChangingFcn = createCallbackFcn(app, @CoperturaSliderValueChanged, true);
            app.NumerostudentimigliaiaSlider.Value = 25;
            app.FrazioneopzionisultotaleButtonGroup = uibuttongroup(app.UIFigure);
            app.FrazioneopzionisultotaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CoperturaSliderValueChanged, true);
            app.FrazioneopzionisultotaleButtonGroup.Title = 'Frazione opzioni sul totale';
            app.FrazioneopzionisultotaleButtonGroup.SizeChangedFcn = createCallbackFcn(app, @CoperturaSliderValueChanged, true);
            app.FrazioneopzionisultotaleButtonGroup.ButtonDownFcn = createCallbackFcn(app, @CoperturaSliderValueChanged, true);
            app.button0 = uiradiobutton(app.FrazioneopzionisultotaleButtonGroup);
            app.button0.Text = '0';
            app.button025 = uiradiobutton(app.FrazioneopzionisultotaleButtonGroup);
            app.button025.Text = '0.25';
            app.button05 = uiradiobutton(app.FrazioneopzionisultotaleButtonGroup);
            app.button05.Text = '0.50';
            app.button075 = uiradiobutton(app.FrazioneopzionisultotaleButtonGroup);
            app.button075.Text = '0.75';
            app.button075.Value = true;
            app.button1 = uiradiobutton(app.FrazioneopzionisultotaleButtonGroup);
            app.button1.Text = '1';
            app.IniziaredalnumerodistudentiesuccessivamenteTextArea = uitextarea(app.UIFigure);
            app.IniziaredalnumerodistudentiesuccessivamenteTextArea.Value = {'GUIDA'; ''; 'Iniziare dalla scelta del numero di studenti e successivamente modificare gli altri due parametri per il grafico in alto a sinistra'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = copertura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end